classdef MLA_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CarModelListBox                matlab.ui.control.ListBox
        CarModelListBoxLabel           matlab.ui.control.Label
        CarBrandListBox                matlab.ui.control.ListBox
        CarBrandListBoxLabel           matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        CarKilometersEditField         matlab.ui.control.NumericEditField
        CarKilometersEditFieldLabel    matlab.ui.control.Label
        CarYearofFabricationEditField  matlab.ui.control.NumericEditField
        CarYearofFabricationEditFieldLabel  matlab.ui.control.Label
        PriceEvaluateAppLabel          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = public)
          CarsDataAfter;
          data;
          userBrand;
          userModel;
          userYear;
          userKilometers;
    end


    methods (Access = private)
        
        function predicted = Prediction(app, userBrand, userModel, userYear, userKilometres)
            
            app.CarsDataAfter = readtable('CarsData After.csv');
            app.data = app.CarsDataAfter;
            
            
            % Feature scaling
            app.data.YearOfFabrication = zscore(app.data.YearOfFabrication);
            app.data.Kilometres = zscore(app.data.Kilometres);
            
            rowsWithSameBrand = strcmpi(app.data.Brand, userBrand);
            rowsWithSameModel = strcmpi(app.data.Model, userModel);
                
            % Find rows where both brand and model match the user input 
            searchRows = rowsWithSameBrand & rowsWithSameModel;
            dataFiltered = app.data(searchRows, :);

    
             if ~isempty(dataFiltered)
        
                    % 'PriceEuro' is the target variable
                    features = dataFiltered(:, {'Brand', 'Model', 'YearOfFabrication', 'Kilometres'});
                    target = dataFiltered.PriceEuro;

                    % Convert categorical variables to dummy variables
                    categoricalVariables = {'Brand', 'Model'};

                    for i = 1:length(categoricalVariables)
                        category = categorical(features.(categoricalVariables{i}));
                        dummyVariables = dummyvar(category);

                        nonZeroColumns = any(dummyVariables ~= 0, 1);
                        dummyVariables = dummyVariables(:, nonZeroColumns);

                        dummyTable = array2table(dummyVariables, 'VariableNames', strcat(categoricalVariables{i}, '_', string(1:size(dummyVariables, 2))));
                        features = [features, dummyTable];
                    end

                    % Remove the original categorical variables
                    features(:, categoricalVariables) = [];

                    % Add quadratic terms for 'YearOfFabrication' and 'Kilometres'
                    features.YearOfFabrication2 = features.YearOfFabrication.^2;
                    features.Kilometres2 = features.Kilometres.^2;

                    % Convert 'YearOfFabrication' and 'Kilometres' to doubles
                    features.YearOfFabrication = double(features.YearOfFabrication);
                    features.Kilometres = double(features.Kilometres);
                    % Convert 'YearOfFabrication2' and 'Kilometres2' to doubles
                    features.YearOfFabrication2 = double(features.YearOfFabrication2);
                    features.Kilometres2 = double(features.Kilometres2);
                    % Convert all variables to numeric arrays
                    features = [table2array(features)];

                    % Split the data into training 
                    
                    
                    % Create training and testing sets
                    XTrain = features(:, :);
                    YTrain = target(:);

                    % Train the machine learning model with elastic net regularization
                    mdl = fitrnet(XTrain, YTrain, "IterationLimit",100, 'Lambda', 0.1);
        
                    % Feature scaling for user input
                    userYear = zscore(userYear);
                    userKilometres = zscore(userKilometres);
                    % Add quadratic terms for user input
                    userYear2 = userYear^2;
                    userKilometers2 = userKilometres^2;

                    % Convert input into categorical
                    userBrandCat = categorical(cellstr(userBrand));
                    userModelCat = categorical(cellstr(userModel));
                    % Convert categorical variables to dummy variables
                    dummyBrand = dummyvar(double(userBrandCat));
                    dummyModel = dummyvar(double(userModelCat));

                    % Create a table with a single row for user input
                    userInput = table(userYear, userKilometres, userYear2, userKilometers2, dummyBrand, dummyModel, 'VariableNames', {'YearOfFabrication', 'Kilometres', 'YearOfFabrication2', 'Kilometres2', 'Brand', 'Model'});

                    % Predict the price for the user's input
                    predicted = predict(mdl, table2array(userInput));
                    predicted = round(predicted);
            end
            
  
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
      app.CarsDataAfter = readtable('CarsData After.csv');
      app.data = app.CarsDataAfter;


            tr = 0;
            % User input for the brand of the car
                app.userBrand = app.CarBrandListBox.Value;
                app.userBrand = strtrim(app.userBrand);  % Remove leading and trailing whitespaces
                dataBrandAsString = cellstr(app.data.Brand);  % Convert to lowercase for case-insensitive comparison

                % Check if there are any brands in the data that match the user brand 
                if any(strcmpi(dataBrandAsString, app.userBrand))           
                    tr = tr+1;
                end


            % User input for the model of the car
                app.userModel = app.CarModelListBox.Value;
                app.userModel = strtrim(app.userModel);  % Remove leading and trailing whitespaces
                dataModelAsString = cellstr(app.data.Model);  % Convert to lowercase for case-insensitive comparison

                % Check if there are any models in the data that match the user model 
                if any(strcmpi(dataModelAsString, app.userModel))
                    tr = tr+1;
                end
            
    
            % User input for the year of the car
                app.userYear = app.CarYearofFabricationEditField.Value;      
                % Check if userYear is within a valid range            
                if app.userYear >= 2000 && app.userYear <= 2023
                    tr = tr+1;
                end       
            

            % User input for the km of the car
                app.userKilometers = app.CarKilometersEditField.Value;            
                % Check if userKilometers is within a valid range
                if app.userKilometers >= 0
                    tr = tr+1;
                end
            
                if tr == 4
                    % Open the second tab 
                    secondTab = MLA_GUI_Tab2(app);  % Pass the instance of MLA_GUI to MLA_GUI_Tab2
                    secondTab.UIFigure.Visible = 'on';  % Make the second tab visible
                    secondTab.ThePredictedPriceEditField.Value = app.Prediction(app.userBrand, app.userModel, app.userYear, app.userKilometers); %Pass the predicted value to the second tab              
                    app.UIFigure.Visible = 'off';  % Make the first tab invisibleelse
                    
                    
                else
                    % Display an error dialog
                    errordlg('Data not found, try again.', 'Error', 'modal');      
                end


        end

        % Value changed function: CarYearofFabricationEditField
        function CarYearofFabricationEditFieldValueChanged(app, event)
            

        end

        % Value changed function: CarKilometersEditField
        function CarKilometersEditFieldValueChanged(app, event)
            

        end

        % Value changed function: CarBrandListBox
        function CarBrandListBoxValueChanged(app, event)
        % Read data from CSV file
        app.CarsDataAfter = readtable('CarsData After.csv');
        app.data = app.CarsDataAfter;

        % Get the selected brand from CarBrandListBox
        theBrand = app.CarBrandListBox.Value;

        % Filter data based on the selected brand
        modelRows = app.data(strcmp(cellstr(app.data.Brand), theBrand), :);

        % Update CarModelListBox items with unique model values
        app.CarModelListBox.Items = cellstr(unique(modelRows.Model));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'cars.jpeg');
            app.PriceEvaluateAppLabel = uilabel(app.UIFigure);
            app.PriceEvaluateAppLabel.Text = 'Price Evaluate App';
            app.CarYearofFabricationEditFieldLabel = uilabel(app.UIFigure);
            app.CarYearofFabricationEditFieldLabel.Text = 'Car Year of Fabrication';
            app.CarYearofFabricationEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarYearofFabricationEditField.ValueChangedFcn = createCallbackFcn(app, @CarYearofFabricationEditFieldValueChanged, true);
            app.CarKilometersEditFieldLabel = uilabel(app.UIFigure);
            app.CarKilometersEditFieldLabel.Text = 'Car Kilometers';
            app.CarKilometersEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarKilometersEditField.ValueChangedFcn = createCallbackFcn(app, @CarKilometersEditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CarBrandListBoxLabel = uilabel(app.UIFigure);
            app.CarBrandListBoxLabel.Text = 'Car Brand';
            app.CarBrandListBox = uilistbox(app.UIFigure);
            app.CarBrandListBox.Items = {'Audi', 'BMW', 'Dacia', 'Ford', 'Hyundai', 'Kia', 'Land Rover', 'Mazda', 'Mercedes-Benz', 'Nissan', 'Opel', 'Peugeot', 'Porsche', 'Renault', 'Skoda', 'Toyota', 'Volkswagen', 'VolvoAudi', 'BMW', 'Dacia', 'Ford', 'Hyundai', 'Kia', 'Land Rover', 'Mazda', 'Mercedes-Benz', 'Nissan', 'Opel', 'Peugeot', 'Porsche', 'Renault', 'Skoda', 'Toyota', 'Volkswagen', 'Volvo'};
            app.CarBrandListBox.ValueChangedFcn = createCallbackFcn(app, @CarBrandListBoxValueChanged, true);
            app.CarBrandListBox.Value = 'Audi';
            app.CarModelListBoxLabel = uilabel(app.UIFigure);
            app.CarModelListBoxLabel.Text = 'Car Model';
            app.CarModelListBox = uilistbox(app.UIFigure);
            app.CarModelListBox.Items = {''};
            app.CarModelListBox.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MLA_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end